classdef bionicmanipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        modelofSPMTab                matlab.ui.container.Tab
        jointangleradPanel           matlab.ui.container.Panel
        lamb1inputEditFieldLabel     matlab.ui.control.Label
        lamb1inputEditField          matlab.ui.control.NumericEditField
        lamb2inputEditFieldLabel     matlab.ui.control.Label
        lamb2inputEditField          matlab.ui.control.NumericEditField
        lamb3inputEditFieldLabel     matlab.ui.control.Label
        lamb3inputEditField          matlab.ui.control.NumericEditField
        Slider1Label                 matlab.ui.control.Label
        Slider1                      matlab.ui.control.Slider
        Slider2Label                 matlab.ui.control.Label
        Slider2                      matlab.ui.control.Slider
        Slider3Label                 matlab.ui.control.Label
        Slider3                      matlab.ui.control.Slider
        SPMmodelPanel                matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        movingcenter                 matlab.ui.control.UIAxes
        posturePanel                 matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        forwordkinematicPanel        matlab.ui.container.Panel
        vectorforButton              matlab.ui.control.Button
        expproductforButton          matlab.ui.control.Button
        sininputButton               matlab.ui.control.Button
        plotaxisandRButton           matlab.ui.control.Button
        inversekinematicPanel        matlab.ui.container.Panel
        vectorinvButton              matlab.ui.control.Button
        expproductinvButton          matlab.ui.control.Button
        atrackButton                 matlab.ui.control.Button
        movingplatpostureradPanel    matlab.ui.container.Panel
        zphi_1EditFieldLabel         matlab.ui.control.Label
        zphi_1EditField              matlab.ui.control.NumericEditField
        SliderzLabel                 matlab.ui.control.Label
        Sliderz                      matlab.ui.control.Slider
        yphi_2EditFieldLabel         matlab.ui.control.Label
        yphi_2EditField              matlab.ui.control.NumericEditField
        SlideryLabel                 matlab.ui.control.Label
        Slidery                      matlab.ui.control.Slider
        zphi_3EditFieldLabel         matlab.ui.control.Label
        zphi_3EditField              matlab.ui.control.NumericEditField
        Sliderz_2Label               matlab.ui.control.Label
        Sliderz_2                    matlab.ui.control.Slider
        JacobianPanel                matlab.ui.container.Panel
        JocoSPM                      matlab.ui.control.Table
        modelofbionicmanipulatorTab  matlab.ui.container.Tab
        jointangleradPanel_2         matlab.ui.container.Panel
        q1EditFieldLabel             matlab.ui.control.Label
        q1EditField                  matlab.ui.control.NumericEditField
        sliq1SliderLabel             matlab.ui.control.Label
        sliq1Slider                  matlab.ui.control.Slider
        q2EditFieldLabel             matlab.ui.control.Label
        q2EditField                  matlab.ui.control.NumericEditField
        sliq2SliderLabel             matlab.ui.control.Label
        sliq2Slider                  matlab.ui.control.Slider
        q3EditFieldLabel             matlab.ui.control.Label
        q3EditField                  matlab.ui.control.NumericEditField
        sliq3SliderLabel             matlab.ui.control.Label
        sliq3Slider                  matlab.ui.control.Slider
        q5EditFieldLabel             matlab.ui.control.Label
        q5EditField                  matlab.ui.control.NumericEditField
        sliq5SliderLabel             matlab.ui.control.Label
        sliq5Slider                  matlab.ui.control.Slider
        q6EditFieldLabel             matlab.ui.control.Label
        q6EditField                  matlab.ui.control.NumericEditField
        sliq6SliderLabel             matlab.ui.control.Label
        sliq6Slider                  matlab.ui.control.Slider
        q7EditFieldLabel             matlab.ui.control.Label
        q7EditField                  matlab.ui.control.NumericEditField
        sliq7SliderLabel             matlab.ui.control.Label
        sliq7Slider                  matlab.ui.control.Slider
        q4EditFieldLabel             matlab.ui.control.Label
        q4EditField                  matlab.ui.control.NumericEditField
        sliq4SliderLabel             matlab.ui.control.Label
        sliq4Slider                  matlab.ui.control.Slider
        BIOMANImodelPanel            matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        positionandposturePanel      matlab.ui.container.Panel
        UITable_2                    matlab.ui.control.Table
        forwordkinematicPanel_2      matlab.ui.container.Panel
        DHforButton                  matlab.ui.control.Button
        POEforButton                 matlab.ui.control.Button
        inversekinematicPanel_2      matlab.ui.container.Panel
        DHinvButton                  matlab.ui.control.Button
        POEinvButton                 matlab.ui.control.Button
        atrackButton_2               matlab.ui.control.Button
        endeffectorpositionandpostureradPanel  matlab.ui.container.Panel
        zphi_1EditField_2Label       matlab.ui.control.Label
        phi_1                        matlab.ui.control.NumericEditField
        sliaSliderLabel              matlab.ui.control.Label
        slia                         matlab.ui.control.Slider
        yphi_2EditField_2Label       matlab.ui.control.Label
        phi_2                        matlab.ui.control.NumericEditField
        slibSliderLabel              matlab.ui.control.Label
        slib                         matlab.ui.control.Slider
        zphi_3EditField_2Label       matlab.ui.control.Label
        phi_3                        matlab.ui.control.NumericEditField
        slicSliderLabel              matlab.ui.control.Label
        slic                         matlab.ui.control.Slider
        xEditFieldLabel              matlab.ui.control.Label
        x                            matlab.ui.control.NumericEditField
        slixSliderLabel              matlab.ui.control.Label
        slix                         matlab.ui.control.Slider
        yEditFieldLabel              matlab.ui.control.Label
        y                            matlab.ui.control.NumericEditField
        sliySliderLabel              matlab.ui.control.Label
        sliy                         matlab.ui.control.Slider
        zEditFieldLabel              matlab.ui.control.Label
        z                            matlab.ui.control.NumericEditField
        slizSliderLabel              matlab.ui.control.Label
        sliz                         matlab.ui.control.Slider
        JacobianPanel_2              matlab.ui.container.Panel
        JacoBIO                      matlab.ui.control.Table
        erroranalysisofSPMTab        matlab.ui.container.Tab
        DOFserialparallelhybridbionicmanipulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta % SPM joint angle
        q % manipulator joint angle
        phi % posture of moving platform
    end
    
    methods (Access = private)
        
        function RRR3invPlot(app,u,v,w)
        % plot SPM
            hold(app.UIAxes,"on")
            view(app.UIAxes,[2,2,6]);
            limit=[-1,1];xlim(app.UIAxes,limit);ylim(app.UIAxes,limit);zlim(app.UIAxes,limit);axis(app.UIAxes,'equal')
            plot3(app.UIAxes,0,0,0,'yo','MarkerFaceColor','k')
            for i=1:3
                plot3(app.UIAxes,u(1,i),u(2,i),u(3,i),'yo','MarkerFaceColor','b')
                plot3(app.UIAxes,v(1,i),v(2,i),v(3,i),'yo','MarkerFaceColor','r')
                plot3(app.UIAxes,w(1,i),w(2,i),w(3,i),'yo','MarkerFaceColor','g')  
                
                plot3(app.UIAxes,[0,u(1,i)],[0,u(2,i)],[0,u(3,i)],'b--')
                plot3(app.UIAxes,[0,w(1,i)],[0,w(2,i)],[0,w(3,i)],'k-')
                plot3(app.UIAxes,[u(1,i),u(1,ceil(mod(i+1,3.1)))],[u(2,i),u(2,ceil(mod(i+1,3.1)))],[u(3,i),u(3,ceil(mod(i+1,3.1)))],'b--')    
                plot3(app.UIAxes,[w(1,i),w(1,ceil(mod(i+1,3.1)))],[w(2,i),w(2,ceil(mod(i+1,3.1)))],[w(3,i),w(3,ceil(mod(i+1,3.1)))],'k-')
                
                plot3(app.UIAxes,[u(1,i),v(1,i)],[u(2,i),v(2,i)],[u(3,i),v(3,i)],'r--')
                plot3(app.UIAxes,[v(1,i),w(1,i)],[v(2,i),w(2,i)],[v(3,i),w(3,i)],'g-')
            end            
            hold(app.UIAxes,"off")
        end
        
        function wholePlot(app,T_BT)
            view(app.UIAxes_2,[4 4 7])
            hold(app.UIAxes_2,"on")
            [~,~,~,~,~,a_3,~,d_7] = paraconfig();            
            [~,m] = solveMedJoiAngPro(T_BT);
            plot3(app.UIAxes_2,[0,0],[0,0],[0,0],'b-')
            plot3(app.UIAxes_2,0,0,0,'bo','MarkerFaceColor','b')
            [~,pj,~,~,~] = RRR3expprod(app.q(1),app.q(2),app.q(3));
            a3=a_3*pj;
            plot3(app.UIAxes_2,[0,a3(1)],[0,a3(2)],[0,a3(3)],'b-')
            plot3(app.UIAxes_2,a3(1),a3(2),a3(3),'bo','MarkerFaceColor','b')
            pd=m-a_3*pj;
            a4=a3+pd;
            plot3(app.UIAxes_2,[a3(1),a4(1)],[a3(2),a4(2)],[a3(3),a4(3)],'b-')
            plot3(app.UIAxes_2,a4(1),a4(2),a4(3),'bo','MarkerFaceColor','b')
            p_=a4+d_7*T_BT(1:3,3);
            plot3(app.UIAxes_2,[p_(1),a4(1)],[p_(2),a4(2)],[p_(3),a4(3)],'b-')
            plot3(app.UIAxes_2,p_(1),p_(2),p_(3),'ro','MarkerFaceColor','r')
            axis(app.UIAxes_2,'equal');
        end
        
        function q2q1_7andsli(app)
            app.q1EditField.Value=app.q(1)/pi*180;
            app.q2EditField.Value=app.q(2)/pi*180;
            app.q3EditField.Value=app.q(3)/pi*180;
            app.q4EditField.Value=app.q(4)/pi*180;
            app.q5EditField.Value=app.q(5)/pi*180;
            app.q6EditField.Value=app.q(6)/pi*180;
            app.q7EditField.Value=app.q(7)/pi*180;
%             app.sliq1Slider.Value=app.q(1)/pi*180;
%             app.sliq2Slider.Value=app.q(2)/pi*180;
%             app.sliq3Slider.Value=app.q(3)/pi*180;
%             app.sliq4Slider.Value=app.q(4)/pi*180;
%             app.sliq5Slider.Value=app.q(5)/pi*180;
%             app.sliq6Slider.Value=app.q(6)/pi*180;
%             app.sliq7Slider.Value=app.q(7)/pi*180;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.theta=[0 0 0].';
            app.q=[0 0 0 0 0 0 0].';
            app.phi=[0 0 0].';
%             app.UITable.Data=eye(4);
%             [~,~,u,v,w] = RRR3inverse(app.phi);
%             RRR3invPlot(app,u,v,w);
%             wholePlot(app)
        end

        % Value changed function: lamb1inputEditField
        function lamb1inputEditFieldValueChanged(app, event)
            app.theta(1) = app.lamb1inputEditField.Value/180*pi;
            app.Slider1.Value = app.lamb1inputEditField.Value;
        end

        % Value changed function: lamb2inputEditField
        function lamb2inputEditFieldValueChanged(app, event)
            app.theta(2) = app.lamb2inputEditField.Value/180*pi;
            app.Slider2.Value = app.lamb2inputEditField.Value;
        end

        % Value changed function: lamb3inputEditField
        function lamb3inputEditFieldValueChanged(app, event)
            app.theta(3) = app.lamb3inputEditField.Value/180*pi;
            app.Slider3.Value = app.lamb3inputEditField.Value;
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            app.lamb1inputEditField.Value = app.Slider1.Value;
            app.theta(1) = app.Slider1.Value/180*pi;
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.lamb2inputEditField.Value = app.Slider2.Value;
            app.theta(2) = app.Slider2.Value/180*pi;
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.lamb3inputEditField.Value = app.Slider3.Value;
            app.theta(3) = app.Slider3.Value/180*pi;
        end

        % Value changed function: zphi_1EditField
        function zphi_1EditFieldValueChanged(app, event)
            value = app.zphi_1EditField.Value/180*pi;
            app.phi(1) = value;
            app.Sliderz.Value=app.zphi_1EditField.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Sliderz
        function SliderzValueChanged(app, event)
            value = app.Sliderz.Value/180*pi;
            app.phi(1) = value;
            app.zphi_1EditField.Value = app.Sliderz.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: yphi_2EditField
        function yphi_2EditFieldValueChanged(app, event)
            value = app.yphi_2EditField.Value/180*pi;
            app.phi(2) = value;
            app.Slidery.Value=app.yphi_2EditField.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Slidery
        function SlideryValueChanged(app, event)
            value = app.Slidery.Value/180*pi;
            app.phi(2) = value;
            app.yphi_2EditField.Value = app.Slidery.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: zphi_3EditField
        function zphi_3EditFieldValueChanged(app, event)
            value = app.zphi_3EditField.Value/180*pi;
            app.phi(3) = value;
            app.Sliderz_2.Value=app.zphi_3EditField.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Sliderz_2
        function Sliderz_2ValueChanged(app, event)
            value = app.Sliderz_2.Value/180*pi;
            app.phi(3) = value;
            app.zphi_3EditField.Value = app.Sliderz_2.Value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Button pushed function: vectorinvButton
        function vectorinvButtonPushed(app, event)
            [app.theta,~,u,v,w] = RRR3inverse(app.phi);
            app.lamb1inputEditField.Value = app.theta(1)/pi*180;app.Slider1.Value=app.theta(1)/pi*180;
            app.lamb2inputEditField.Value = app.theta(2)/pi*180;app.Slider2.Value=app.theta(2)/pi*180;
            app.lamb3inputEditField.Value = app.theta(3)/pi*180;app.Slider3.Value=app.theta(3)/pi*180;
            app.UITable.Data=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            app.JocoSPM.Data=RRR3Jacobian(u,v,w);
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end

        % Button pushed function: expproductinvButton
        function expproductinvButtonPushed(app, event)
            R=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            [thetatmp,~,~] = RRR3expprodinvPro(R);
            app.theta=thetatmp(:,2);
            app.lamb1inputEditField.Value = app.theta(1)/pi*180;app.Slider1.Value=app.theta(1)/pi*180;
            app.lamb2inputEditField.Value = app.theta(2)/pi*180;app.Slider2.Value=app.theta(2)/pi*180;
            app.lamb3inputEditField.Value = app.theta(3)/pi*180;app.Slider3.Value=app.theta(3)/pi*180;
            app.UITable.Data=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            cla(app.UIAxes,"reset")
            [~,~,u,v,w] = RRR3expprodPro(app.theta);
            app.JocoSPM.Data=RRR3JacobianPOE(u,v,w);
            RRR3invPlot(app,u,v,w);
        end

        % Button pushed function: vectorforButton
        function vectorforButtonPushed(app, event)
            app.phi = RRR3forward_theta123(app.theta);
            app.zphi_1EditField.Value=app.phi(1)/pi*180;
            app.Sliderz.Value=app.phi(1)/pi*180;
            app.yphi_2EditField.Value=app.phi(2)/pi*180;
            app.Slidery.Value=app.phi(2)/pi*180;
            app.zphi_3EditField.Value=app.phi(3)/pi*180;
            app.Sliderz_2.Value=app.phi(3)/pi*180;
            app.UITable.Data=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            [~,~,u,v,w] = RRR3inverse(app.phi);
            app.JocoSPM.Data=RRR3Jacobian(u,v,w);
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end

        % Button pushed function: expproductforButton
        function expproductforButtonPushed(app, event)
            [R,~,u,v,w] = RRR3expprodPro(app.theta);
            [app.phi(1) ,app.phi(2) ,app.phi(3)] = r2zyz(R);
            app.zphi_1EditField.Value=app.phi(1)/pi*180;            app.Sliderz.Value=app.phi(1)/pi*180;
            app.yphi_2EditField.Value=app.phi(2)/pi*180;            app.Slidery.Value=app.phi(2)/pi*180;
            app.zphi_3EditField.Value=app.phi(3)/pi*180;            app.Sliderz_2.Value=app.phi(3)/pi*180;
            app.UITable.Data=R;
            app.JocoSPM.Data=RRR3JacobianPOE(u,v,w);
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: atrackButton
        function atrackButtonPushed(app, event)
                num=10;
            if app.phi==0
                a=linspace(0,pi/14,num);
                b=linspace(0,pi/13,num);
                c=linspace(0,pi/11,num);
            else
                a=linspace(0,app.phi(1),num);
                b=linspace(0,app.phi(2),num);
                c=linspace(0,app.phi(3),num);
            end
            for i=1:num
                R=rotz(a(i))*roty(b(i))*rotz(c(i));
                [thetatmp,~,~] = RRR3expprodinvPro(R);
                app.theta=thetatmp(:,2);
                app.zphi_1EditField.Value=a(i)/pi*180;                app.Sliderz.Value=a(i)/pi*180;
                app.yphi_2EditField.Value=b(i)/pi*180;                app.Slidery.Value=b(i)/pi*180;
                app.zphi_3EditField.Value=c(i)/pi*180;                app.Sliderz_2.Value=c(i)/pi*180;                
                app.lamb1inputEditField.Value = app.theta(1)/pi*180;                app.Slider1.Value=app.theta(1)/pi*180;
                app.lamb2inputEditField.Value = app.theta(2)/pi*180;                app.Slider2.Value=app.theta(2)/pi*180;
                app.lamb3inputEditField.Value = app.theta(3)/pi*180;                app.Slider3.Value=app.theta(3)/pi*180;
                app.UITable.Data(1:3,1:3)=R;
                cla(app.UIAxes,"reset")
                [~,~,u,v,w] = RRR3expprodPro(app.theta);
                RRR3invPlot(app,u,v,w);
                app.JocoSPM.Data=RRR3JacobianPOE(u,v,w);
                pause(0.1);
            end
        end

        % Button pushed function: sininputButton
        function sininputButtonPushed(app, event)
            SPM3RRRown;
        end

        % Button pushed function: plotaxisandRButton
        function plotaxisandRButtonPushed(app, event)
%             axi=load('axis.mat');
            R=load('R.mat');
            p=zeros(3,length(R.R.Time));
            for i=1:length(R.R.Time)
                p(:,i)=R.R.Data(:,3,i);
            end
            plot3(app.movingcenter,p(1,:),p(2,:),p(3,:),'b.','MarkerFaceColor','b');
%             plot3(app.movingcenter,axi.axi.Data(:,1),axi.axi.Data(:,2),axi.axi.Data(:,3),'b.','MarkerFaceColor','b');
            for i=1:100:length(R.R.Time)
                Rval=R.R.Data(:,:,i);
                app.UITable.Data=Rval;
                [thetatmp,~,~] = RRR3expprodinvPro(Rval);
                app.theta=thetatmp(:,2);
                app.lamb1inputEditField.Value = app.theta(1)/pi*180;app.Slider1.Value=app.theta(1)/pi*180;
                app.lamb2inputEditField.Value = app.theta(2)/pi*180;app.Slider2.Value=app.theta(2)/pi*180;
                app.lamb3inputEditField.Value = app.theta(3)/pi*180;app.Slider3.Value=app.theta(3)/pi*180;
                app.UITable.Data=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
                cla(app.UIAxes,"reset")
                [~,~,u,v,w] = RRR3expprodPro(app.theta);
                RRR3invPlot(app,u,v,w);
                app.JocoSPM.Data=RRR3JacobianPOE(u,v,w);
                pause(0.05)
            end
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            value = app.q1EditField.Value;
            app.q(1)=value/180*pi;
            app.sliq1Slider.Value=value;
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
            value = app.q2EditField.Value;
            app.q(2)=value/180*pi;
            app.sliq2Slider.Value=value;
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
            value = app.q3EditField.Value;
            app.q(3)=value/180*pi;
            app.sliq3Slider.Value=value;
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
            value = app.q4EditField.Value;
            app.q(4)=value/180*pi;
            app.sliq4Slider.Value=value;
        end

        % Value changed function: q5EditField
        function q5EditFieldValueChanged(app, event)
            value = app.q5EditField.Value;
            app.q(5)=value/180*pi;
            app.sliq5Slider.Value=value;
        end

        % Value changed function: q6EditField
        function q6EditFieldValueChanged(app, event)
            value = app.q6EditField.Value;
            app.q(6)=value/180*pi;
            app.sliq6Slider.Value=value;
        end

        % Value changed function: q7EditField
        function q7EditFieldValueChanged(app, event)
            value = app.q7EditField.Value;
            app.q(7)=value/180*pi;
            app.sliq7Slider.Value=value;
        end

        % Value changed function: sliq1Slider
        function sliq1SliderValueChanged(app, event)
            value = app.sliq1Slider.Value;
            app.q(1)=value/180*pi;
            app.q1EditField.Value=value;
        end

        % Value changed function: sliq2Slider
        function sliq2SliderValueChanged(app, event)
            value = app.sliq2Slider.Value;
            app.q(2)=value/180*pi;
            app.q2EditField.Value=value;
        end

        % Value changed function: sliq3Slider
        function sliq3SliderValueChanged(app, event)
            value = app.sliq3Slider.Value;
            app.q(3)=value/180*pi;
            app.q3EditField.Value=value;
        end

        % Value changed function: sliq4Slider
        function sliq4SliderValueChanged(app, event)
            value = app.sliq4Slider.Value;
            app.q(4)=value/180*pi;
            app.q4EditField.Value=value;
        end

        % Value changed function: sliq5Slider
        function sliq5SliderValueChanged(app, event)
            value = app.sliq5Slider.Value;
            app.q(5)=value/180*pi;
            app.q5EditField.Value=value;
        end

        % Value changed function: sliq6Slider
        function sliq6SliderValueChanged(app, event)
            value = app.sliq6Slider.Value;
            app.q(6)=value/180*pi;
            app.q6EditField.Value=value;
        end

        % Value changed function: sliq7Slider
        function sliq7SliderValueChanged(app, event)
            value = app.sliq7Slider.Value;
            app.q(7)=value/180*pi;
            app.q7EditField.Value=value;
        end

        % Value changed function: x
        function xValueChanged(app, event)
            app.slix.Value = app.x.Value;            
        end

        % Value changed function: y
        function yValueChanged(app, event)
            app.sliy.Value = app.y.Value;            
        end

        % Value changed function: z
        function zValueChanged(app, event)
            app.sliz.Value = app.z.Value;            
        end

        % Value changed function: phi_1
        function phi_1ValueChanged(app, event)
            app.slia.Value = app.phi_1.Value;            
        end

        % Value changed function: phi_2
        function phi_2ValueChanged(app, event)
            app.slib.Value = app.phi_2.Value;            
        end

        % Value changed function: phi_3
        function phi_3ValueChanged(app, event)
            app.slic.Value = app.phi_3.Value;            
        end

        % Value changed function: slix
        function slixValueChanged(app, event)
            app.x.Value = app.slix.Value;            
        end

        % Value changed function: sliy
        function sliyValueChanged(app, event)
            app.y.Value = app.sliy.Value;            
        end

        % Value changed function: sliz
        function slizValueChanged(app, event)
            app.z.Value = app.sliz.Value;            
        end

        % Value changed function: slia
        function sliaValueChanged(app, event)
            app.phi_1.Value = app.slia.Value;            
        end

        % Value changed function: slib
        function slibValueChanged(app, event)
            app.phi_2.Value = app.slib.Value;            
        end

        % Value changed function: slic
        function slicValueChanged(app, event)
            app.phi_3.Value = app.slic.Value;            
        end

        % Button pushed function: DHforButton
        function DHforButtonPushed(app, event)
            T = wholeforward(app.q);
            app.x.Value=T(1,4);app.slix.Value=T(1,4);
            app.y.Value=T(2,4);app.sliy.Value=T(2,4);
            app.z.Value=T(3,4);app.sliz.Value=T(3,4);
            [a,b,c]=r2zyz(T(1:3,1:3));
            app.phi_1.Value=a/pi*180;app.slia.Value=a/pi*180;
            app.phi_2.Value=b/pi*180;app.slib.Value=b/pi*180;
            app.phi_3.Value=c/pi*180;app.slic.Value=c/pi*180;
            app.UITable_2.Data=T;
            [LL,rr,R_B5,uj,vj,wj,ud,vd,wd]  = wholegetL(T);
            app.JacoBIO.Data = wholevelocityJacobian(LL,rr,R_B5,T,uj,vj,wj,ud,vd,wd);
            cla(app.UIAxes_2,"reset")
            wholePlot(app,T);
        end

        % Button pushed function: POEforButton
        function POEforButtonPushed(app, event)
            T = wholePOEforward(app.q);
            app.x.Value=T(1,4);app.slix.Value=T(1,4);
            app.y.Value=T(2,4);app.sliy.Value=T(2,4);
            app.z.Value=T(3,4);app.sliz.Value=T(3,4);
            [a,b,c]=r2zyz(T(1:3,1:3));
            app.phi_1.Value=a/pi*180;app.slia.Value=a/pi*180;
            app.phi_2.Value=b/pi*180;app.slib.Value=b/pi*180;
            app.phi_3.Value=c/pi*180;app.slic.Value=c/pi*180;
            app.UITable_2.Data=T;
            [LL,rr,R_B5,uj,vj,wj,ud,vd,wd]  = wholegetL(T);
            app.JacoBIO.Data = wholeJacobian(LL,rr,R_B5,uj,vj,wj,ud,vd,wd);
            cla(app.UIAxes_2,"reset")
            wholePlot(app,T);
        end

        % Button pushed function: DHinvButton
        function DHinvButtonPushed(app, event)
            T=eye(4);
            T(1:3,4)=[app.x.Value app.y.Value app.z.Value].';
            T(1:3,1:3)=rotz(app.phi_1.Value)*roty(app.phi_2.Value)*rotz(app.phi_3.Value);
            app.UITable_2.Data=T;
            app.q=wholeinversePro(T);
            q2q1_7andsli(app);
            [LL,rr,R_B5,uj,vj,wj,ud,vd,wd]  = wholegetL(T);
            app.JacoBIO.Data = wholevelocityJacobian(LL,rr,R_B5,T,uj,vj,wj,ud,vd,wd);
            cla(app.UIAxes_2,"reset")
            wholePlot(app,T);
        end

        % Button pushed function: POEinvButton
        function POEinvButtonPushed(app, event)
            T=eye(4);
            T(1:3,4)=[app.x.Value app.y.Value app.z.Value].';
            T(1:3,1:3)=rotz(app.phi_1.Value)*roty(app.phi_2.Value)*rotz(app.phi_3.Value);
            app.UITable_2.Data=T;
            app.q=wholePOEinverse(T);
            q2q1_7andsli(app);
            [LL,rr,R_B5,uj,vj,wj,ud,vd,wd]  = wholegetL(T);
            app.JacoBIO.Data = wholeJacobian(LL,rr,R_B5,uj,vj,wj,ud,vd,wd);
            cla(app.UIAxes_2,"reset")
            wholePlot(app,T);
        end

        % Button pushed function: atrackButton_2
        function atrackButton_2Pushed(app, event)
            T_history=load('T_history.mat');
            r=70;
            count=1;
            for the=0:pi/5:2*pi
                T_BT=T_history.T_history{50};
                T_BT(1,4)=T_BT(1,4)-50+r*cos(the);
                T_BT(2,4)=T_BT(2,4)-50+r*sin(the);
                app.x.Value=T_BT(1,4);app.slix.Value=T_BT(1,4);
                app.y.Value=T_BT(2,4);app.sliy.Value=T_BT(2,4);
                app.z.Value=T_BT(3,4);app.sliz.Value=T_BT(3,4);
                [a,b,c]=r2zyz(T_BT(1:3,1:3));
                app.phi_1.Value=a/pi*180;app.slia.Value=a/pi*180;
                app.phi_2.Value=b/pi*180;app.slib.Value=b/pi*180;
                app.phi_3.Value=c/pi*180;app.slic.Value=c/pi*180;
                app.UITable_2.Data=T_BT;
                app.q = wholePOEinverse(T_BT);
                q2q1_7andsli(app);
                [LL,rr,R_B5,uj,vj,wj,ud,vd,wd]  = wholegetL(T_BT);
                app.JacoBIO.Data = wholeJacobian(LL,rr,R_B5,uj,vj,wj,ud,vd,wd);
                wholePlot(app,T_BT);
                q_hitory(:,count)=app.q(:,1);
                count=count+1;
                pause(0.1);
            end
            pause(3);
            cla(app.UIAxes_2,"reset")
            plot(app.UIAxes_2,q_hitory.'/pi*180);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.modelofSPMTab = uitab(app.TabGroup);
            app.modelofSPMTab.Title = 'model of SPM';
            app.jointangleradPanel = uipanel(app.modelofSPMTab);
            app.jointangleradPanel.Title = 'joint angle(rad)';
            app.lamb1inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb1inputEditFieldLabel.Text = 'lamb1 input';
            app.lamb1inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb1inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb1inputEditFieldValueChanged, true);
            app.lamb2inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb2inputEditFieldLabel.Text = 'lamb2 input';
            app.lamb2inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb2inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb2inputEditFieldValueChanged, true);
            app.lamb3inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb3inputEditFieldLabel.Text = 'lamb3 input';
            app.lamb3inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb3inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb3inputEditFieldValueChanged, true);
            app.Slider1Label = uilabel(app.jointangleradPanel);
            app.Slider1Label.Text = 'Slider1';
            app.Slider1 = uislider(app.jointangleradPanel);
            app.Slider1.Limits = [-30 90];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider2Label = uilabel(app.jointangleradPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.jointangleradPanel);
            app.Slider2.Limits = [-30 90];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider3Label = uilabel(app.jointangleradPanel);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.jointangleradPanel);
            app.Slider3.Limits = [-30 90];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.SPMmodelPanel = uipanel(app.modelofSPMTab);
            app.SPMmodelPanel.Title = 'SPM model';
            app.UIAxes = uiaxes(app.SPMmodelPanel);
            title(app.UIAxes, 'SPM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.movingcenter = uiaxes(app.SPMmodelPanel);
            title(app.movingcenter, 'moving center')
            xlabel(app.movingcenter, 'X')
            ylabel(app.movingcenter, 'Y')
            app.posturePanel = uipanel(app.modelofSPMTab);
            app.posturePanel.Title = 'posture';
            app.UITable = uitable(app.posturePanel);
            app.UITable.ColumnName = {'n'; 'o'; 'a'};
            app.UITable.RowName = {};
            app.forwordkinematicPanel = uipanel(app.modelofSPMTab);
            app.forwordkinematicPanel.Title = 'forword kinematic';
            app.vectorforButton = uibutton(app.forwordkinematicPanel, 'push');
            app.vectorforButton.ButtonPushedFcn = createCallbackFcn(app, @vectorforButtonPushed, true);
            app.vectorforButton.Text = 'vector for';
            app.expproductforButton = uibutton(app.forwordkinematicPanel, 'push');
            app.expproductforButton.ButtonPushedFcn = createCallbackFcn(app, @expproductforButtonPushed, true);
            app.expproductforButton.Text = 'exp product for';
            app.sininputButton = uibutton(app.forwordkinematicPanel, 'push');
            app.sininputButton.ButtonPushedFcn = createCallbackFcn(app, @sininputButtonPushed, true);
            app.sininputButton.Text = 'sin input';
            app.plotaxisandRButton = uibutton(app.forwordkinematicPanel, 'push');
            app.plotaxisandRButton.ButtonPushedFcn = createCallbackFcn(app, @plotaxisandRButtonPushed, true);
            app.plotaxisandRButton.Text = 'plot axis and R';
            app.inversekinematicPanel = uipanel(app.modelofSPMTab);
            app.inversekinematicPanel.Title = 'inverse kinematic';
            app.vectorinvButton = uibutton(app.inversekinematicPanel, 'push');
            app.vectorinvButton.ButtonPushedFcn = createCallbackFcn(app, @vectorinvButtonPushed, true);
            app.vectorinvButton.Text = 'vector inv';
            app.expproductinvButton = uibutton(app.inversekinematicPanel, 'push');
            app.expproductinvButton.ButtonPushedFcn = createCallbackFcn(app, @expproductinvButtonPushed, true);
            app.expproductinvButton.Text = 'exp product inv';
            app.atrackButton = uibutton(app.inversekinematicPanel, 'push');
            app.atrackButton.ButtonPushedFcn = createCallbackFcn(app, @atrackButtonPushed, true);
            app.atrackButton.Text = 'a track';
            app.movingplatpostureradPanel = uipanel(app.modelofSPMTab);
            app.movingplatpostureradPanel.Title = 'moving plat posture(rad)';
            app.zphi_1EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.zphi_1EditFieldLabel.Text = 'z-phi_1';
            app.zphi_1EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.zphi_1EditField.ValueChangedFcn = createCallbackFcn(app, @zphi_1EditFieldValueChanged, true);
            app.SliderzLabel = uilabel(app.movingplatpostureradPanel);
            app.SliderzLabel.Text = 'Sliderz';
            app.Sliderz = uislider(app.movingplatpostureradPanel);
            app.Sliderz.Limits = [-180 180];
            app.Sliderz.ValueChangedFcn = createCallbackFcn(app, @SliderzValueChanged, true);
            app.yphi_2EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.yphi_2EditFieldLabel.Text = 'y-phi_2';
            app.yphi_2EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.yphi_2EditField.ValueChangedFcn = createCallbackFcn(app, @yphi_2EditFieldValueChanged, true);
            app.SlideryLabel = uilabel(app.movingplatpostureradPanel);
            app.SlideryLabel.Text = 'Slidery';
            app.Slidery = uislider(app.movingplatpostureradPanel);
            app.Slidery.Limits = [-180 180];
            app.Slidery.ValueChangedFcn = createCallbackFcn(app, @SlideryValueChanged, true);
            app.zphi_3EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.zphi_3EditFieldLabel.Text = 'z-phi_3';
            app.zphi_3EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.zphi_3EditField.ValueChangedFcn = createCallbackFcn(app, @zphi_3EditFieldValueChanged, true);
            app.Sliderz_2Label = uilabel(app.movingplatpostureradPanel);
            app.Sliderz_2Label.Text = 'Sliderz';
            app.Sliderz_2 = uislider(app.movingplatpostureradPanel);
            app.Sliderz_2.Limits = [-180 180];
            app.Sliderz_2.ValueChangedFcn = createCallbackFcn(app, @Sliderz_2ValueChanged, true);
            app.JacobianPanel = uipanel(app.modelofSPMTab);
            app.JacobianPanel.Title = 'Jacobian';
            app.JocoSPM = uitable(app.JacobianPanel);
            app.JocoSPM.ColumnName = {'Ja1'; 'Ja2'; 'Ja3'};
            app.JocoSPM.RowName = {};
            app.modelofbionicmanipulatorTab = uitab(app.TabGroup);
            app.modelofbionicmanipulatorTab.Title = 'model of bionic manipulator';
            app.jointangleradPanel_2 = uipanel(app.modelofbionicmanipulatorTab);
            app.jointangleradPanel_2.Title = 'joint angle(rad)';
            app.q1EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.sliq1SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq1SliderLabel.Text = 'sliq1';
            app.sliq1Slider = uislider(app.jointangleradPanel_2);
            app.sliq1Slider.Limits = [-30 90];
            app.sliq1Slider.ValueChangedFcn = createCallbackFcn(app, @sliq1SliderValueChanged, true);
            app.q2EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.sliq2SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq2SliderLabel.Text = 'sliq2';
            app.sliq2Slider = uislider(app.jointangleradPanel_2);
            app.sliq2Slider.Limits = [-30 90];
            app.sliq2Slider.ValueChangedFcn = createCallbackFcn(app, @sliq2SliderValueChanged, true);
            app.q3EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.sliq3SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq3SliderLabel.Text = 'sliq3';
            app.sliq3Slider = uislider(app.jointangleradPanel_2);
            app.sliq3Slider.Limits = [-30 90];
            app.sliq3Slider.ValueChangedFcn = createCallbackFcn(app, @sliq3SliderValueChanged, true);
            app.q5EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q5EditFieldValueChanged, true);
            app.sliq5SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq5SliderLabel.Text = 'sliq5';
            app.sliq5Slider = uislider(app.jointangleradPanel_2);
            app.sliq5Slider.Limits = [-30 90];
            app.sliq5Slider.ValueChangedFcn = createCallbackFcn(app, @sliq5SliderValueChanged, true);
            app.q6EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.sliq6SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq6SliderLabel.Text = 'sliq6';
            app.sliq6Slider = uislider(app.jointangleradPanel_2);
            app.sliq6Slider.Limits = [-30 90];
            app.sliq6Slider.ValueChangedFcn = createCallbackFcn(app, @sliq6SliderValueChanged, true);
            app.q7EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q7EditFieldLabel.Text = 'q7';
            app.q7EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q7EditField.ValueChangedFcn = createCallbackFcn(app, @q7EditFieldValueChanged, true);
            app.sliq7SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq7SliderLabel.Text = 'sliq7';
            app.sliq7Slider = uislider(app.jointangleradPanel_2);
            app.sliq7Slider.Limits = [-30 90];
            app.sliq7Slider.ValueChangedFcn = createCallbackFcn(app, @sliq7SliderValueChanged, true);
            app.q4EditFieldLabel = uilabel(app.jointangleradPanel_2);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.jointangleradPanel_2, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.sliq4SliderLabel = uilabel(app.jointangleradPanel_2);
            app.sliq4SliderLabel.Text = 'sliq4';
            app.sliq4Slider = uislider(app.jointangleradPanel_2);
            app.sliq4Slider.ValueChangedFcn = createCallbackFcn(app, @sliq4SliderValueChanged, true);
            app.BIOMANImodelPanel = uipanel(app.modelofbionicmanipulatorTab);
            app.BIOMANImodelPanel.Title = 'BIO MANI model';
            app.UIAxes_2 = uiaxes(app.BIOMANImodelPanel);
            title(app.UIAxes_2, 'BIO MANI')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.positionandposturePanel = uipanel(app.modelofbionicmanipulatorTab);
            app.positionandposturePanel.Title = 'position and posture';
            app.UITable_2 = uitable(app.positionandposturePanel);
            app.UITable_2.ColumnName = {'n'; 'o'; 'a'; 'p'};
            app.UITable_2.RowName = {};
            app.forwordkinematicPanel_2 = uipanel(app.modelofbionicmanipulatorTab);
            app.forwordkinematicPanel_2.Title = 'forword kinematic';
            app.DHforButton = uibutton(app.forwordkinematicPanel_2, 'push');
            app.DHforButton.ButtonPushedFcn = createCallbackFcn(app, @DHforButtonPushed, true);
            app.DHforButton.Text = 'DH for';
            app.POEforButton = uibutton(app.forwordkinematicPanel_2, 'push');
            app.POEforButton.ButtonPushedFcn = createCallbackFcn(app, @POEforButtonPushed, true);
            app.POEforButton.Text = 'POE for';
            app.inversekinematicPanel_2 = uipanel(app.modelofbionicmanipulatorTab);
            app.inversekinematicPanel_2.Title = 'inverse kinematic';
            app.DHinvButton = uibutton(app.inversekinematicPanel_2, 'push');
            app.DHinvButton.ButtonPushedFcn = createCallbackFcn(app, @DHinvButtonPushed, true);
            app.DHinvButton.Text = 'DH inv';
            app.POEinvButton = uibutton(app.inversekinematicPanel_2, 'push');
            app.POEinvButton.ButtonPushedFcn = createCallbackFcn(app, @POEinvButtonPushed, true);
            app.POEinvButton.Text = 'POE inv';
            app.atrackButton_2 = uibutton(app.inversekinematicPanel_2, 'push');
            app.atrackButton_2.ButtonPushedFcn = createCallbackFcn(app, @atrackButton_2Pushed, true);
            app.atrackButton_2.Text = 'a track';
            app.endeffectorpositionandpostureradPanel = uipanel(app.modelofbionicmanipulatorTab);
            app.endeffectorpositionandpostureradPanel.Title = 'end effector position and posture(rad)';
            app.zphi_1EditField_2Label = uilabel(app.endeffectorpositionandpostureradPanel);
            app.zphi_1EditField_2Label.Text = 'z-phi_1';
            app.phi_1 = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.phi_1.ValueChangedFcn = createCallbackFcn(app, @phi_1ValueChanged, true);
            app.sliaSliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.sliaSliderLabel.Text = 'slia';
            app.slia = uislider(app.endeffectorpositionandpostureradPanel);
            app.slia.Limits = [-180 180];
            app.slia.ValueChangedFcn = createCallbackFcn(app, @sliaValueChanged, true);
            app.yphi_2EditField_2Label = uilabel(app.endeffectorpositionandpostureradPanel);
            app.yphi_2EditField_2Label.Text = 'y-phi_2';
            app.phi_2 = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.phi_2.ValueChangedFcn = createCallbackFcn(app, @phi_2ValueChanged, true);
            app.slibSliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.slibSliderLabel.Text = 'slib';
            app.slib = uislider(app.endeffectorpositionandpostureradPanel);
            app.slib.Limits = [-180 180];
            app.slib.ValueChangedFcn = createCallbackFcn(app, @slibValueChanged, true);
            app.zphi_3EditField_2Label = uilabel(app.endeffectorpositionandpostureradPanel);
            app.zphi_3EditField_2Label.Text = 'z-phi_3';
            app.phi_3 = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.phi_3.ValueChangedFcn = createCallbackFcn(app, @phi_3ValueChanged, true);
            app.slicSliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.slicSliderLabel.Text = 'slic';
            app.slic = uislider(app.endeffectorpositionandpostureradPanel);
            app.slic.Limits = [-180 180];
            app.slic.ValueChangedFcn = createCallbackFcn(app, @slicValueChanged, true);
            app.xEditFieldLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.xEditFieldLabel.Text = 'x';
            app.x = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.x.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.slixSliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.slixSliderLabel.Text = 'slix';
            app.slix = uislider(app.endeffectorpositionandpostureradPanel);
            app.slix.Limits = [-540 540];
            app.slix.ValueChangedFcn = createCallbackFcn(app, @slixValueChanged, true);
            app.yEditFieldLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.yEditFieldLabel.Text = 'y';
            app.y = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.y.ValueChangedFcn = createCallbackFcn(app, @yValueChanged, true);
            app.sliySliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.sliySliderLabel.Text = 'sliy';
            app.sliy = uislider(app.endeffectorpositionandpostureradPanel);
            app.sliy.Limits = [-540 540];
            app.sliy.ValueChangedFcn = createCallbackFcn(app, @sliyValueChanged, true);
            app.zEditFieldLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.zEditFieldLabel.Text = 'z';
            app.z = uieditfield(app.endeffectorpositionandpostureradPanel, 'numeric');
            app.z.ValueChangedFcn = createCallbackFcn(app, @zValueChanged, true);
            app.slizSliderLabel = uilabel(app.endeffectorpositionandpostureradPanel);
            app.slizSliderLabel.Text = 'sliz';
            app.sliz = uislider(app.endeffectorpositionandpostureradPanel);
            app.sliz.Limits = [-540 540];
            app.sliz.ValueChangedFcn = createCallbackFcn(app, @slizValueChanged, true);
            app.JacobianPanel_2 = uipanel(app.modelofbionicmanipulatorTab);
            app.JacobianPanel_2.Title = 'Jacobian';
            app.JacoBIO = uitable(app.JacobianPanel_2);
            app.JacoBIO.ColumnName = {'J1'; 'J2'; 'J3'; 'J4'; 'J5'; 'J6'; 'J7'};
            app.JacoBIO.RowName = {};
            app.erroranalysisofSPMTab = uitab(app.TabGroup);
            app.erroranalysisofSPMTab.Title = 'error analysis of SPM';
            app.DOFserialparallelhybridbionicmanipulatorLabel = uilabel(app.UIFigure);
            app.DOFserialparallelhybridbionicmanipulatorLabel.Text = '7-DOF serial-parallel hybrid bionic manipulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bionicmanipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end