classdef bionicmanipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        modelofSPMTab                matlab.ui.container.Tab
        jointangleradPanel           matlab.ui.container.Panel
        lamb1inputEditFieldLabel     matlab.ui.control.Label
        lamb1inputEditField          matlab.ui.control.NumericEditField
        lamb2inputEditFieldLabel     matlab.ui.control.Label
        lamb2inputEditField          matlab.ui.control.NumericEditField
        lamb3inputEditFieldLabel     matlab.ui.control.Label
        lamb3inputEditField          matlab.ui.control.NumericEditField
        Slider1Label                 matlab.ui.control.Label
        Slider1                      matlab.ui.control.Slider
        Slider2Label                 matlab.ui.control.Label
        Slider2                      matlab.ui.control.Slider
        Slider3Label                 matlab.ui.control.Label
        Slider3                      matlab.ui.control.Slider
        modelPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        positionandposturePanel      matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        forwordkinematicPanel        matlab.ui.container.Panel
        vectorforButton              matlab.ui.control.Button
        expproductforButton          matlab.ui.control.Button
        inversekinematicPanel        matlab.ui.container.Panel
        vectorinvButton              matlab.ui.control.Button
        expproductinvButton          matlab.ui.control.Button
        atrackButton                 matlab.ui.control.Button
        movingplatpostureradPanel    matlab.ui.container.Panel
        zphi_1EditFieldLabel         matlab.ui.control.Label
        zphi_1EditField              matlab.ui.control.NumericEditField
        SliderzLabel                 matlab.ui.control.Label
        Sliderz                      matlab.ui.control.Slider
        yphi_2EditFieldLabel         matlab.ui.control.Label
        yphi_2EditField              matlab.ui.control.NumericEditField
        SlideryLabel                 matlab.ui.control.Label
        Slidery                      matlab.ui.control.Slider
        zphi_3EditFieldLabel         matlab.ui.control.Label
        zphi_3EditField              matlab.ui.control.NumericEditField
        Sliderz_2Label               matlab.ui.control.Label
        Sliderz_2                    matlab.ui.control.Slider
        modelofbionicmanipulatorTab  matlab.ui.container.Tab
        erroranalysisofSPMTab        matlab.ui.container.Tab
        DOFserialparallelhybridbionicmanipulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta % SPM joint angle
        q % manipulator joint angle
        phi % posture of moving platform
    end
    
    methods (Access = private)
        
        function RRR3invPlot(app,u,v,w)
        % plot SPM
            hold(app.UIAxes,"on")
            view(app.UIAxes,[6,2,2]);
            limit=[-1,1];xlim(app.UIAxes,limit);ylim(app.UIAxes,limit);zlim(app.UIAxes,limit);axis(app.UIAxes,'equal')
            plot3(app.UIAxes,0,0,0,'yo','MarkerFaceColor','k')
            for i=1:3
                plot3(app.UIAxes,u(1,i),u(2,i),u(3,i),'yo','MarkerFaceColor','b')
                plot3(app.UIAxes,v(1,i),v(2,i),v(3,i),'yo','MarkerFaceColor','r')
                plot3(app.UIAxes,w(1,i),w(2,i),w(3,i),'yo','MarkerFaceColor','g')  
                
                plot3(app.UIAxes,[0,u(1,i)],[0,u(2,i)],[0,u(3,i)],'b--')
                plot3(app.UIAxes,[0,w(1,i)],[0,w(2,i)],[0,w(3,i)],'k--')
                plot3(app.UIAxes,[u(1,i),u(1,ceil(mod(i+1,3.1)))],[u(2,i),u(2,ceil(mod(i+1,3.1)))],[u(3,i),u(3,ceil(mod(i+1,3.1)))],'b--')    
                plot3(app.UIAxes,[w(1,i),w(1,ceil(mod(i+1,3.1)))],[w(2,i),w(2,ceil(mod(i+1,3.1)))],[w(3,i),w(3,ceil(mod(i+1,3.1)))],'k--')
                
                plot3(app.UIAxes,[u(1,i),v(1,i)],[u(2,i),v(2,i)],[u(3,i),v(3,i)],'r-')
                plot3(app.UIAxes,[v(1,i),w(1,i)],[v(2,i),w(2,i)],[v(3,i),w(3,i)],'g-')
            end            
            hold(app.UIAxes,"off")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.theta=[0 0 0].';
            app.q=[0 0 0 0 0 0 0].';
            app.phi=[0 0 0].';
%             app.UITable.Data=eye(4);
            [~,~,u,v,w] = RRR3inverse(app.phi);
            RRR3invPlot(app,u,v,w);
        end

        % Value changed function: lamb1inputEditField
        function lamb1inputEditFieldValueChanged(app, event)
            app.theta(1) = app.lamb1inputEditField.Value;
            app.Slider1.Value = app.lamb1inputEditField.Value;
        end

        % Value changed function: lamb2inputEditField
        function lamb2inputEditFieldValueChanged(app, event)
            app.theta(2) = app.lamb2inputEditField.Value;
            app.Slider2.Value = app.lamb2inputEditField.Value;
        end

        % Value changed function: lamb3inputEditField
        function lamb3inputEditFieldValueChanged(app, event)
            app.theta(3) = app.lamb3inputEditField.Value;
            app.Slider3.Value = app.lamb3inputEditField.Value;
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            app.lamb1inputEditField.Value = app.Slider1.Value;
            app.theta(1) = app.Slider1.Value;
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.lamb2inputEditField.Value = app.Slider2.Value;
            app.theta(2) = app.Slider2.Value;
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.lamb3inputEditField.Value = app.Slider3.Value;
            app.theta(3) = app.Slider3.Value;
        end

        % Value changed function: zphi_1EditField
        function zphi_1EditFieldValueChanged(app, event)
            value = app.zphi_1EditField.Value;
            app.phi(1) = value;
            app.Sliderz.Value=value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Sliderz
        function SliderzValueChanged(app, event)
            value = app.Sliderz.Value;
            app.phi(1) = value;
            app.zphi_1EditField.Value = value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: yphi_2EditField
        function yphi_2EditFieldValueChanged(app, event)
            value = app.yphi_2EditField.Value;
            app.phi(2) = value;
            app.Slidery.Value=value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Slidery
        function SlideryValueChanged(app, event)
            value = app.Slidery.Value;
            app.phi(2) = value;
            app.yphi_2EditField.Value = value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: zphi_3EditField
        function zphi_3EditFieldValueChanged(app, event)
            value = app.zphi_3EditField.Value;
            app.phi(3) = value;
            app.Sliderz_2.Value=value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Value changed function: Sliderz_2
        function Sliderz_2ValueChanged(app, event)
            value = app.Sliderz_2.Value;
            app.phi(3) = value;
            app.zphi_3EditField.Value = value;
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
        end

        % Button pushed function: vectorinvButton
        function vectorinvButtonPushed(app, event)
            [app.theta,~,u,v,w] = RRR3inverse(app.phi);
            app.lamb1inputEditField.Value = app.theta(1);app.Slider1.Value=app.theta(1);
            app.lamb2inputEditField.Value = app.theta(2);app.Slider2.Value=app.theta(2);
            app.lamb3inputEditField.Value = app.theta(3);app.Slider3.Value=app.theta(3);
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end

        % Button pushed function: expproductinvButton
        function expproductinvButtonPushed(app, event)
            R=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            [thetatmp,~,~] = RRR3expprodinvPro(R);
            app.theta=thetatmp(:,2);
            app.lamb1inputEditField.Value = app.theta(1);app.Slider1.Value=app.theta(1);
            app.lamb2inputEditField.Value = app.theta(2);app.Slider2.Value=app.theta(2);
            app.lamb3inputEditField.Value = app.theta(3);app.Slider3.Value=app.theta(3);
            cla(app.UIAxes,"reset")
            [~,~,u,v,w] = RRR3expprodPro(app.theta);
            RRR3invPlot(app,u,v,w);
        end

        % Button pushed function: vectorforButton
        function vectorforButtonPushed(app, event)
            app.phi = RRR3forward_theta123(app.theta);
            app.zphi_1EditField.Value=app.phi(1);
            app.Sliderz.Value=app.phi(1);
            app.yphi_2EditField.Value=app.phi(2);
            app.Slidery.Value=app.phi(2);
            app.zphi_3EditField.Value=app.phi(3);
            app.Sliderz_2.Value=app.phi(3);
            app.UITable.Data(1:3,1:3)=rotz(app.phi(1))*roty(app.phi(2))*rotz(app.phi(3));
            [~,~,u,v,w] = RRR3inverse(app.phi);
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: atrackButton
        function atrackButtonPushed(app, event)
                num=10;
            if app.phi==0
                a=linspace(0,pi/14,num);
                b=linspace(0,pi/13,num);
                c=linspace(0,pi/11,num);
            else
                a=linspace(0,app.phi(1),num);
                b=linspace(0,app.phi(2),num);
                c=linspace(0,app.phi(3),num);
            end
            for i=1:num
                R=rotz(a(i))*roty(b(i))*rotz(c(i));
                [thetatmp,~,~] = RRR3expprodinvPro(R);
                app.theta=thetatmp(:,2);
                app.zphi_1EditField.Value=a(i);                app.Sliderz.Value=a(i);
                app.yphi_2EditField.Value=b(i);                app.Slidery.Value=b(i);
                app.zphi_3EditField.Value=c(i);                app.Sliderz_2.Value=c(i);                
                app.lamb1inputEditField.Value = app.theta(1);                app.Slider1.Value=app.theta(1);
                app.lamb2inputEditField.Value = app.theta(2);                app.Slider2.Value=app.theta(2);
                app.lamb3inputEditField.Value = app.theta(3);                app.Slider3.Value=app.theta(3);
                app.UITable.Data(1:3,1:3)=R;
                cla(app.UIAxes,"reset")
                [~,~,u,v,w] = RRR3expprodPro(app.theta);
                RRR3invPlot(app,u,v,w);
                pause(0.1);
            end
        end

        % Button pushed function: expproductforButton
        function expproductforButtonPushed(app, event)
            [R,~,u,v,w] = RRR3expprodPro(app.theta);
            [app.phi(1) ,app.phi(2) ,app.phi(3)] = r2zyz(R);
            app.zphi_1EditField.Value=app.phi(1);            app.Sliderz.Value=app.phi(1);
            app.yphi_2EditField.Value=app.phi(2);            app.Slidery.Value=app.phi(2);
            app.zphi_3EditField.Value=app.phi(3);            app.Sliderz_2.Value=app.phi(3);
            app.UITable.Data(1:3,1:3)=R;
            cla(app.UIAxes,"reset")
            RRR3invPlot(app,u,v,w);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.modelofSPMTab = uitab(app.TabGroup);
            app.modelofSPMTab.Title = 'model of SPM';
            app.jointangleradPanel = uipanel(app.modelofSPMTab);
            app.jointangleradPanel.Title = 'joint angle(rad)';
            app.lamb1inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb1inputEditFieldLabel.Text = 'lamb1 input';
            app.lamb1inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb1inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb1inputEditFieldValueChanged, true);
            app.lamb2inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb2inputEditFieldLabel.Text = 'lamb2 input';
            app.lamb2inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb2inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb2inputEditFieldValueChanged, true);
            app.lamb3inputEditFieldLabel = uilabel(app.jointangleradPanel);
            app.lamb3inputEditFieldLabel.Text = 'lamb3 input';
            app.lamb3inputEditField = uieditfield(app.jointangleradPanel, 'numeric');
            app.lamb3inputEditField.ValueChangedFcn = createCallbackFcn(app, @lamb3inputEditFieldValueChanged, true);
            app.Slider1Label = uilabel(app.jointangleradPanel);
            app.Slider1Label.Text = 'Slider1';
            app.Slider1 = uislider(app.jointangleradPanel);
            app.Slider1.Limits = [-0.523598775598299 1.5707963267949];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider2Label = uilabel(app.jointangleradPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.jointangleradPanel);
            app.Slider2.Limits = [-0.523598775598299 1.5707963267949];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider3Label = uilabel(app.jointangleradPanel);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.jointangleradPanel);
            app.Slider3.Limits = [-0.523598775598299 1.5707963267949];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.modelPanel = uipanel(app.modelofSPMTab);
            app.modelPanel.Title = 'model';
            app.UIAxes = uiaxes(app.modelPanel);
            title(app.UIAxes, 'SPM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.positionandposturePanel = uipanel(app.modelofSPMTab);
            app.positionandposturePanel.Title = 'position and posture';
            app.UITable = uitable(app.positionandposturePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.forwordkinematicPanel = uipanel(app.modelofSPMTab);
            app.forwordkinematicPanel.Title = 'forword kinematic';
            app.vectorforButton = uibutton(app.forwordkinematicPanel, 'push');
            app.vectorforButton.ButtonPushedFcn = createCallbackFcn(app, @vectorforButtonPushed, true);
            app.vectorforButton.Text = 'vector for';
            app.expproductforButton = uibutton(app.forwordkinematicPanel, 'push');
            app.expproductforButton.ButtonPushedFcn = createCallbackFcn(app, @expproductforButtonPushed, true);
            app.expproductforButton.Text = 'exp product for';
            app.inversekinematicPanel = uipanel(app.modelofSPMTab);
            app.inversekinematicPanel.Title = 'inverse kinematic';
            app.vectorinvButton = uibutton(app.inversekinematicPanel, 'push');
            app.vectorinvButton.ButtonPushedFcn = createCallbackFcn(app, @vectorinvButtonPushed, true);
            app.vectorinvButton.Text = 'vector inv';
            app.expproductinvButton = uibutton(app.inversekinematicPanel, 'push');
            app.expproductinvButton.ButtonPushedFcn = createCallbackFcn(app, @expproductinvButtonPushed, true);
            app.expproductinvButton.Text = 'exp product inv';
            app.atrackButton = uibutton(app.inversekinematicPanel, 'push');
            app.atrackButton.ButtonPushedFcn = createCallbackFcn(app, @atrackButtonPushed, true);
            app.atrackButton.Text = 'a track';
            app.movingplatpostureradPanel = uipanel(app.modelofSPMTab);
            app.movingplatpostureradPanel.Title = 'moving plat posture(rad)';
            app.zphi_1EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.zphi_1EditFieldLabel.Text = 'z-phi_1';
            app.zphi_1EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.zphi_1EditField.ValueChangedFcn = createCallbackFcn(app, @zphi_1EditFieldValueChanged, true);
            app.SliderzLabel = uilabel(app.movingplatpostureradPanel);
            app.SliderzLabel.Text = 'Sliderz';
            app.Sliderz = uislider(app.movingplatpostureradPanel);
            app.Sliderz.Limits = [-3.14159265358979 3.14159265358979];
            app.Sliderz.ValueChangedFcn = createCallbackFcn(app, @SliderzValueChanged, true);
            app.yphi_2EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.yphi_2EditFieldLabel.Text = 'y-phi_2';
            app.yphi_2EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.yphi_2EditField.ValueChangedFcn = createCallbackFcn(app, @yphi_2EditFieldValueChanged, true);
            app.SlideryLabel = uilabel(app.movingplatpostureradPanel);
            app.SlideryLabel.Text = 'Slidery';
            app.Slidery = uislider(app.movingplatpostureradPanel);
            app.Slidery.Limits = [-3.14159265358979 3.14159265358979];
            app.Slidery.ValueChangedFcn = createCallbackFcn(app, @SlideryValueChanged, true);
            app.zphi_3EditFieldLabel = uilabel(app.movingplatpostureradPanel);
            app.zphi_3EditFieldLabel.Text = 'z-phi_3';
            app.zphi_3EditField = uieditfield(app.movingplatpostureradPanel, 'numeric');
            app.zphi_3EditField.ValueChangedFcn = createCallbackFcn(app, @zphi_3EditFieldValueChanged, true);
            app.Sliderz_2Label = uilabel(app.movingplatpostureradPanel);
            app.Sliderz_2Label.Text = 'Sliderz';
            app.Sliderz_2 = uislider(app.movingplatpostureradPanel);
            app.Sliderz_2.Limits = [-3.14159265358979 3.14159265358979];
            app.Sliderz_2.ValueChangedFcn = createCallbackFcn(app, @Sliderz_2ValueChanged, true);
            app.modelofbionicmanipulatorTab = uitab(app.TabGroup);
            app.modelofbionicmanipulatorTab.Title = 'model of bionic manipulator';
            app.erroranalysisofSPMTab = uitab(app.TabGroup);
            app.erroranalysisofSPMTab.Title = 'error analysis of SPM';
            app.DOFserialparallelhybridbionicmanipulatorLabel = uilabel(app.UIFigure);
            app.DOFserialparallelhybridbionicmanipulatorLabel.Text = '7-DOF serial-parallel hybrid bionic manipulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bionicmanipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end