classdef referencefinalsoundwavanalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        FILEPATH                    matlab.ui.control.EditField
        CHOOSEPATH                  matlab.ui.control.Button
        TIMECH                      matlab.ui.control.UIAxes
        ONETHIRDANALYSIS            matlab.ui.control.UIAxes
        STOPSOUND                   matlab.ui.control.Button
        FANALYSIS                   matlab.ui.control.UIAxes
        f_t_A                       matlab.ui.control.UIAxes
        Label_6                     matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label_2                     matlab.ui.control.Label
        Peak                        matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        VPP                         matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        Average                     matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        RMS                         matlab.ui.control.NumericEditField
        audioplay                   matlab.ui.control.Button
        Label_7                     matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        Frequency11100HzLabel       matlab.ui.control.Label
        F1_1                        matlab.ui.control.Slider
        filteron                    matlab.ui.control.Button
        filteroff                   matlab.ui.control.Button
        Frequency2100200HzLabel     matlab.ui.control.Label
        F1_2                        matlab.ui.control.Slider
        Frequency3200500HzLabel     matlab.ui.control.Label
        F1_3                        matlab.ui.control.Slider
        Frequency45001000HzLabel    matlab.ui.control.Label
        F1_4                        matlab.ui.control.Slider
        Frequency510002000HzLabel   matlab.ui.control.Label
        F1_5                        matlab.ui.control.Slider
        Frequency620004000HzLabel   matlab.ui.control.Label
        F1_6                        matlab.ui.control.Slider
        Frequency8800020000HzLabel  matlab.ui.control.Label
        F1_8                        matlab.ui.control.Slider
        Frequency740008000HzLabel   matlab.ui.control.Label
        F1_7                        matlab.ui.control.Slider
        clear                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        filepath
        filename
        state
        N
        newtimer
    end
    
    
    methods (Access = private)
        function results = filter_FIR(~,fs, wc1 ,wc2 , signal)
            b = fir1(50, [wc1*2/fs wc2*2/fs]);
            results = filtfilt(b,1,signal);                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %音频文件播放及1/3倍频程分析。
            app.filepath='C:\Users\RM\Desktop\工程测试技术实验七\';
            app.filename='跨越时代的思念.mp3';
            app.FILEPATH.Value= [app.filepath,app.filename];
            global SoundFFT
            SoundFFT=[];
            global SoundY 
            SoundY=[];
            global savedSoundY
            global SoundFs
            SoundFs=[];
            global value; 
            global starttime;
            global endtime;
            starttime=0;
            endtime=4410;
            app.state=0;
            set(app.Lamp,'color',[0,0,0]);
            global N
            N=4410;
            global newtimer;
            newtimer=timer('StartDelay',0,'Period',0.1,'TasksToExecute',10000,'ExecutionMode','fixedRate');
            newtimer.TimerFcn={@func,app}; 
            global f
            global fc
            global oc6
            global nc
            f = [1.00 1.25 1.60 2.00 2.50 3.15 4.00 5.00 6.300 8.00]; 
            fc = [f,10*f,100*f,1000*f,10000*f]; 
            oc6 = 2^(1/6); 
            nc = length(fc);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: STOPSOUND
        function STOPSOUNDButtonPushed(app, event)
            clear sound;
            global newtimer;
            stop(newtimer);
            set(app.Lamp,'color',[0,0,0]);
        end

        % Button pushed function: CHOOSEPATH
        function CHOOSEPATHButtonPushed(app, event)
            [app.filename, app.filepath]=uigetfile('*.mp3','请选择文件');
            app.FILEPATH.Value=[app.filepath,app.filename]; 
            
        end

        % Button pushed function: audioplay
        function audioplayButtonPushed(app, event)
            set(app.Lamp,'color',[0,1,0]);
            cla(app.ONETHIRDANALYSIS)
            cla(app.f_t_A)
            cla(app.TIMECH)
            cla(app.FANALYSIS)
            global SoundFs
            global SoundY
            [SoundY,SoundFs] = audioread(app.FILEPATH.Value);
            global savedSoundY 
            savedSoundY=SoundY;
            global starttime;
            global endtime;
            starttime=0;
            endtime=4410;
            global newtimer
            sound(SoundY,SoundFs);
            start(newtimer);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
        global value;    
        value = app.DropDown.Value;
            
        end

        % Button pushed function: filteron
        function filteronButtonPushed(app, event)
            clear sound;            
            set(app.Lamp,'color',[0,1,0]);
            global newtimer;
            stop(newtimer);
            global SoundY
            global SoundFs            
            global savedSoundY            
            sound_100 = filter_FIR(app, SoundFs, 1 ,100 , savedSoundY);
            sound_200 = filter_FIR(app, SoundFs, 100 ,200 , savedSoundY);
            sound_500 = filter_FIR(app, SoundFs, 200 ,500 , savedSoundY);
            sound_1K = filter_FIR(app, SoundFs, 500 ,1000 , savedSoundY);
            sound_2K = filter_FIR(app, SoundFs, 1000 ,2000 , savedSoundY);
            sound_4K = filter_FIR(app, SoundFs, 2000 ,4000 , savedSoundY);
            sound_8K = filter_FIR(app, SoundFs, 4000 ,8000 , savedSoundY);
            sound_16K = filter_FIR(app, SoundFs, 8000 ,20000 , savedSoundY);
            a = app.F1_1.Value;
            b = app.F1_2.Value;
            c = app.F1_3.Value;
            d = app.F1_4.Value;
            e = app.F1_5.Value;
            f = app.F1_6.Value;
            g = app.F1_7.Value;
            h = app.F1_8.Value;
            SoundY=a*sound_100 + b*sound_200 + c*sound_500 + d*sound_1K + e*sound_2K + f*sound_4K + g*sound_8K + h*sound_16K;
            global starttime;
            global endtime;
            starttime=0;
            endtime=4410;
            sound(SoundY,SoundFs)
            cla(app.ONETHIRDANALYSIS)
            cla(app.f_t_A)
            cla(app.TIMECH)
            cla(app.FANALYSIS)
            start(newtimer)
        end

        % Button pushed function: filteroff
        function filteroffButtonPushed(app, event)
            clear sound;
            set(app.Lamp,'color',[0,0,0]);
            global newtimer;
            stop(newtimer);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            cla(app.ONETHIRDANALYSIS)
            cla(app.f_t_A)
            cla(app.TIMECH)
            cla(app.FANALYSIS)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.FILEPATH = uieditfield(app.UIFigure, 'text');
            app.CHOOSEPATH = uibutton(app.UIFigure, 'push');
            app.CHOOSEPATH.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATH.Text = '选择音频文件';
            app.TIMECH = uiaxes(app.UIFigure);
            title(app.TIMECH, '时域信号')
            xlabel(app.TIMECH, 't')
            ylabel(app.TIMECH, 'Y')
            app.ONETHIRDANALYSIS = uiaxes(app.UIFigure);
            title(app.ONETHIRDANALYSIS, '1/3倍频程分析')
            xlabel(app.ONETHIRDANALYSIS, 'f')
            ylabel(app.ONETHIRDANALYSIS, 'Y')
            app.STOPSOUND = uibutton(app.UIFigure, 'push');
            app.STOPSOUND.ButtonPushedFcn = createCallbackFcn(app, @STOPSOUNDButtonPushed, true);
            app.STOPSOUND.Text = '停止播放';
            app.FANALYSIS = uiaxes(app.UIFigure);
            title(app.FANALYSIS, '频域分析')
            xlabel(app.FANALYSIS, 'log10(f)')
            ylabel(app.FANALYSIS, 'Y')
            app.f_t_A = uiaxes(app.UIFigure);
            title(app.f_t_A, '谱阵分析')
            xlabel(app.f_t_A, 'f')
            ylabel(app.f_t_A, 't')
            app.f_t_A.ZTick = [0 0.5 1];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '运行状态：';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '峰值';
            app.Peak = uieditfield(app.UIFigure, 'numeric');
            app.Peak.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '峰峰值';
            app.VPP = uieditfield(app.UIFigure, 'numeric');
            app.VPP.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '平均值';
            app.Average = uieditfield(app.UIFigure, 'numeric');
            app.Average.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '有效值';
            app.RMS = uieditfield(app.UIFigure, 'numeric');
            app.RMS.Editable = 'off';
            app.audioplay = uibutton(app.UIFigure, 'push');
            app.audioplay.ButtonPushedFcn = createCallbackFcn(app, @audioplayButtonPushed, true);
            app.audioplay.Text = '播放音频';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '频域分析';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'功率谱', '幅频', '相频', '实频', '虚频'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '功率谱';
            app.Frequency11100HzLabel = uilabel(app.UIFigure);
            app.Frequency11100HzLabel.Text = {'Frequency1'; '1-100Hz'};
            app.F1_1 = uislider(app.UIFigure);
            app.F1_1.Limits = [0 1];
            app.F1_1.Value = 1;
            app.filteron = uibutton(app.UIFigure, 'push');
            app.filteron.ButtonPushedFcn = createCallbackFcn(app, @filteronButtonPushed, true);
            app.filteron.Text = '开启均衡器';
            app.filteroff = uibutton(app.UIFigure, 'push');
            app.filteroff.ButtonPushedFcn = createCallbackFcn(app, @filteroffButtonPushed, true);
            app.filteroff.Text = '关闭均衡器';
            app.Frequency2100200HzLabel = uilabel(app.UIFigure);
            app.Frequency2100200HzLabel.Text = {'Frequency2'; '100-200Hz'};
            app.F1_2 = uislider(app.UIFigure);
            app.F1_2.Limits = [0 1];
            app.F1_2.Value = 1;
            app.Frequency3200500HzLabel = uilabel(app.UIFigure);
            app.Frequency3200500HzLabel.Text = {'Frequency3'; '200-500Hz'};
            app.F1_3 = uislider(app.UIFigure);
            app.F1_3.Limits = [0 1];
            app.F1_3.Value = 1;
            app.Frequency45001000HzLabel = uilabel(app.UIFigure);
            app.Frequency45001000HzLabel.Text = {'Frequency4'; '500-1000Hz'};
            app.F1_4 = uislider(app.UIFigure);
            app.F1_4.Limits = [0 1];
            app.F1_4.Value = 1;
            app.Frequency510002000HzLabel = uilabel(app.UIFigure);
            app.Frequency510002000HzLabel.Text = {'Frequency5'; '1000-2000Hz'};
            app.F1_5 = uislider(app.UIFigure);
            app.F1_5.Limits = [0 1];
            app.F1_5.Value = 1;
            app.Frequency620004000HzLabel = uilabel(app.UIFigure);
            app.Frequency620004000HzLabel.Text = {'Frequency6'; '2000-4000Hz'};
            app.F1_6 = uislider(app.UIFigure);
            app.F1_6.Limits = [0 1];
            app.F1_6.Value = 1;
            app.Frequency8800020000HzLabel = uilabel(app.UIFigure);
            app.Frequency8800020000HzLabel.Text = {'Frequency8'; '8000-20000Hz'};
            app.F1_8 = uislider(app.UIFigure);
            app.F1_8.Limits = [0 1];
            app.F1_8.Value = 1;
            app.Frequency740008000HzLabel = uilabel(app.UIFigure);
            app.Frequency740008000HzLabel.Text = {'Frequency7'; '4000-8000Hz'};
            app.F1_7 = uislider(app.UIFigure);
            app.F1_7.Limits = [0 1];
            app.F1_7.Value = 1;
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清除图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = referencefinalsoundwavanalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end